classdef AverageHistogramAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        HelpButton                    matlab.ui.control.Button
        ClearListButton               matlab.ui.control.Button
        FilelistListBoxLabel          matlab.ui.control.Label
        FilelistListBox               matlab.ui.control.ListBox
        FoldersincludedListBoxLabel   matlab.ui.control.Label
        FoldersincludedListBox        matlab.ui.control.ListBox
        AddFolderButton               matlab.ui.control.Button
        AnalyzeSelectedFilesButton    matlab.ui.control.Button
        ColorchannelEditFieldLabel    matlab.ui.control.Label
        ColorchannelEditField         matlab.ui.control.EditField
        NumberoffilesEditFieldLabel   matlab.ui.control.Label
        NumberoffilesEditField        matlab.ui.control.NumericEditField
        RightPanel                    matlab.ui.container.Panel
        TabGroup                      matlab.ui.container.TabGroup
        IndividualimageanalysisTab    matlab.ui.container.Tab
        UITable                       matlab.ui.control.Table
        UIAxes_histSelect             matlab.ui.control.UIAxes
        UIAxes_imSelect               matlab.ui.control.UIAxes
        FiguresbasedonallimagesTab    matlab.ui.container.Tab
        UIAxes_histTotal              matlab.ui.control.UIAxes
        UIAxes_cdfTotal               matlab.ui.control.UIAxes
        UIAxes_endpoints              matlab.ui.control.UIAxes
        StatsbasedonallimagesPanel    matlab.ui.container.Panel
        PercentileSpinnerLabel        matlab.ui.control.Label
        PercentileSpinner             matlab.ui.control.Spinner
        PercentileintensityEditFieldLabel  matlab.ui.control.Label
        PercentileintensityEditField  matlab.ui.control.NumericEditField
        TotalMaximumEditFieldLabel    matlab.ui.control.Label
        TotalMaximumEditField         matlab.ui.control.NumericEditField
        TotalMinimumEditFieldLabel    matlab.ui.control.Label
        TotalMinimumEditField         matlab.ui.control.NumericEditField
        TotalMeanEditFieldLabel       matlab.ui.control.Label
        TotalMeanEditField            matlab.ui.control.NumericEditField
        UIAxes_imAVG                  matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        selected_image
        selected_image_pos
        total_image
        average_image
        
        UIAxes_prct
        
        Table1
        
    end
    
    methods (Access = private)
        
        
        function displayEndPointsScatterplot(app)
            MeanIntensity = app.Table1.('Mean Intensity');
            x = ones(1, length(MeanIntensity));
            plot(x, MeanIntensity, 'bx', 'Parent',app.UIAxes_endpoints);
            hold(app.UIAxes_endpoints, "on");
            
            x = 1;
            plot(x, app.TotalMeanEditField.Value, 'b_', 'MarkerSize', 20, 'Parent',app.UIAxes_endpoints);
            
            Thresh = app.Table1.('Percentile Intensity');
            x = 1.15* ones(1, length(Thresh));
            plot(x, Thresh, 'rx', 'Parent',app.UIAxes_endpoints);
            
            x = 1.15;
            plot(x, app.PercentileintensityEditField.Value, 'r_', 'MarkerSize', 20, 'Parent',app.UIAxes_endpoints);
            
            grid(app.UIAxes_endpoints, "on");
            app.UIAxes_endpoints.XLim = [0.9, 1.3];
            app.UIAxes_endpoints.XTick = [1, 1.15];
            app.UIAxes_endpoints.XTickLabels = {'Mean Intensity','Percentile Intensity'};
            
            hold(app.UIAxes_endpoints, "off");
        end
        
        
        function updateSelectedImage(app)
            imshow(app.selected_image,[],'Parent',app.UIAxes_imSelect);
            histogram(app.selected_image,'BinMethod','integers','Normalization', 'probability','Parent',app.UIAxes_histSelect, 'EdgeColor', 'none', 'FaceColor', 'blue');
            %title('Parent', app.UIAxes_imSelect, app.UITable.Data(indices(1),2).('File Name'), 'Interpreter', 'none');
        end
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {763, 763};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {376, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: AddFolderButton
        function AddFolderButtonPushed(app, event)
            try
                folder = uigetdir;
                
                if ~ismember(folder, app.FoldersincludedListBox.Items)
                    prog = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                        'Message','Loading file paths');
                    cd(folder)
                    FileList = dir(fullfile(folder, '*.TIF'));
                    app.FoldersincludedListBox.Items{end+1} = folder;
                    for ii = 1:size(FileList,1)
                        app.FilelistListBox.Items{end+1} = fullfile(FileList(ii).folder, FileList(ii).name);
                        prog.Value = ii/size(FileList,1);
                    end
                    app.FilelistListBox.Value = {};
                    
                    prog.Message = 'Displaying file list';
                    pause(0.2);
                    close(prog);
                else
                    uialert(app.UIFigure, 'This folder is already included', 'Something Went Wrong');
                end
                
                
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Something Went Wrong');
                return;
            end 
        end

        % Button pushed function: ClearListButton
        function ClearListButtonPushed(app, event)
            app.FoldersincludedListBox.Value = {};
            app.FilelistListBox.Value = {};
            app.FoldersincludedListBox.Items = {};
            app.FilelistListBox.Items = {};
        end

        % Value changed function: FoldersincludedListBox
        function FoldersincludedListBoxValueChanged(app, event)
            app.FoldersincludedListBox.Value = {};
        end

        % Value changed function: FilelistListBox
        function FilelistListBoxValueChanged(app, event)
            prog = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                'Message','Searching for corresponding files');
            
            selected_file = app.FilelistListBox.Value{1};
            app.FilelistListBox.Value = app.FilelistListBox.Value{1};
            app.selected_image = imread(selected_file);
            %updateSelectedImage(app);
            
            num_img = 0;
            selected_channel = selected_file(end-5:end);
            
            for ii = 1:size(app.FilelistListBox.Items, 2)
                current_file = app.FilelistListBox.Items{ii};
                current_channel = current_file(end-5:end);
                
                if (current_channel == selected_channel)
                    current_img = imread(current_file);
                    
                    if(size(current_img) == size(app.selected_image))
                        
                        num_img = num_img + 1;
                        
                        if ~ismember(current_file, app.FilelistListBox.Value)
                            app.FilelistListBox.Value{end+1} = current_file;
                        else
                            app.selected_image_pos = num_img;
                            
                        end

                    end
                end
                prog.Value = ii/size(app.FilelistListBox.Items, 2);
            end
            
            app.ColorchannelEditField.Value = selected_channel(1:2);
            app.NumberoffilesEditField.Value = num_img;
            
            prog.Message = 'Almost done';
            pause(1);
            close(prog);                       
        end

        % Button pushed function: AnalyzeSelectedFilesButton
        function AnalyzeSelectedFilesButtonPushed(app, event)
            prog = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                'Message','Analyzing images');
            
            selected_file = app.FilelistListBox.Value{1};
            app.FilelistListBox.Value = app.FilelistListBox.Value{1};
            app.selected_image = imread(selected_file);
            updateSelectedImage(app);
            
            selected_channel = selected_file(end-5:end);
            app.ColorchannelEditField.Value = selected_channel(1:2);
            
            %app.total_image = [];%zeros(size(imread(selected_file)));
            num_img = 0;
            
            sz = [0 4];
            varTypes = {'int16', 'string','double','double'};
            varNames = {'#', 'File Name','Mean Intensity','Percentile Intensity'};
            app.Table1 = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
            
            %figure;
            
            for ii = 1:size(app.FilelistListBox.Items, 2)
                current_file = app.FilelistListBox.Items{ii};
                current_channel = current_file(end-5:end);
                
                if (current_channel == selected_channel)
                    current_img = imread(current_file);
                    
                    if(size(current_img) == size(app.selected_image))
                        
                        num_img = num_img + 1;
                        [~,current_file_name,~] = fileparts(current_file);
                        app.Table1(end+1,:) = {num_img, current_file_name,mean(current_img(:)), prctile(current_img(:), app.PercentileSpinner.Value)};
                        %app.Table1(end+1,:) = {current_file,mean(current_img(:)), prctile(current_img(:), app.PercentileSpinner.Value)};
                        app.total_image(:,:,num_img) = double(current_img);
                        
                        if ~ismember(current_file, app.FilelistListBox.Value)
                            app.FilelistListBox.Value{end+1} = current_file;
                        else
                            app.selected_image_pos = num_img;
                            
                        end
                        
                        %imshow(app.total_image,[]);
                    end
                end
                prog.Value = ii/size(app.FilelistListBox.Items, 2);
            end
            
            prog.Message = 'Displaying average image';
            app.average_image = mean(app.total_image, 3);
            imshow(app.average_image,[],'Parent',app.UIAxes_imAVG);
            
            prog.Message = 'Displaying histogram';
            histogram(app.total_image(:),'BinMethod','integers','Normalization', 'probability','Parent',app.UIAxes_histTotal, 'EdgeColor', 'none', 'FaceColor', 'blue');
            
            prog.Message = 'Displaying cumulative density function';
            histogram(app.total_image(:),'BinMethod','integers','Normalization', 'cdf','Parent',app.UIAxes_cdfTotal,'EdgeColor', 'none', 'FaceColor', 'red');
            
            app.TotalMaximumEditField.Value = max(app.total_image, [], 'all');
            app.TotalMinimumEditField.Value = min(app.total_image, [], 'all');
            app.TotalMeanEditField.Value = mean(app.total_image, 'all');
            
            
            app.UITable.Data = app.Table1;
            removeStyle(app.UITable);
            s1 = uistyle('BackgroundColor','y', 'FontColor', 'black');
            addStyle(app.UITable,s1,'cell', [app.selected_image_pos 2]);
            app.UIAxes_imSelect.Title.BackgroundColor = 'y';
            app.UIAxes_histSelect.Title.BackgroundColor = 'y';
            
            PercentileSpinnerValueChanged(app, event)
            
            prog.Message = 'Displaying endpoints';
            displayEndPointsScatterplot(app);
            
            prog.Message = 'Almost done';
            pause(1);
            close(prog);                    
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            message = sprintf('Please choose folder containing d0, d1, d2, d3,...');
            uialert(app.UIFigure, message,'Help',...
            'Icon','info');
        end

        % Value changed function: PercentileSpinner
        function PercentileSpinnerValueChanged(app, event)
            app.PercentileintensityEditField.Value = prctile(app.total_image(:), app.PercentileSpinner.Value);
            
            delete(app.UIAxes_prct);
            app.UIAxes_prct = xline(app.UIAxes_histTotal, app.PercentileintensityEditField.Value, '--r');
        end

        % Value changed function: PercentileintensityEditField
        function PercentileintensityEditFieldValueChanged(app, event)
            delete(app.UIAxes_prct);
            app.UIAxes_prct = xline(app.UIAxes_histTotal, app.PercentileintensityEditField.Value, '--r');
            
            num_less = sum(app.total_image(:) < app.PercentileintensityEditField.Value);
            num_equal = sum(app.total_image(:) == app.PercentileintensityEditField.Value);
            app.PercentileSpinner.Value = (num_less + 0.5*num_equal)*100 / length(app.total_image(:));
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            
            if (size(indices,1) == 1 && app.selected_image_pos ~= app.UITable.Data(indices(1),1).('#'))
                prog = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                    'Message','Updating selected image');
                
                app.selected_image_pos = app.UITable.Data(indices(1),1).('#');
                app.selected_image = app.total_image(:,:,app.selected_image_pos);
                removeStyle(app.UITable);
                s1 = uistyle('BackgroundColor','y', 'FontColor', 'black');
                addStyle(app.UITable,s1,'cell',[app.selected_image_pos 2]);
                updateSelectedImage(app);
                
                prog.Value = 1;
                prog.Message = 'Almost done';
                pause(1);
                close(prog);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.HelpButton = uibutton(app.LeftPanel, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.ClearListButton = uibutton(app.LeftPanel, 'push');
            app.ClearListButton.ButtonPushedFcn = createCallbackFcn(app, @ClearListButtonPushed, true);
            app.ClearListButton.Text = 'Clear List';
            app.FilelistListBoxLabel = uilabel(app.LeftPanel);
            app.FilelistListBoxLabel.Text = 'File list:';
            app.FilelistListBox = uilistbox(app.LeftPanel);
            app.FilelistListBox.Items = {};
            app.FilelistListBox.Multiselect = 'on';
            app.FilelistListBox.ValueChangedFcn = createCallbackFcn(app, @FilelistListBoxValueChanged, true);
            app.FilelistListBox.Value = {};
            app.FoldersincludedListBoxLabel = uilabel(app.LeftPanel);
            app.FoldersincludedListBoxLabel.Text = 'Folders included:';
            app.FoldersincludedListBox = uilistbox(app.LeftPanel);
            app.FoldersincludedListBox.Items = {};
            app.FoldersincludedListBox.ValueChangedFcn = createCallbackFcn(app, @FoldersincludedListBoxValueChanged, true);
            app.FoldersincludedListBox.Value = {};
            app.AddFolderButton = uibutton(app.LeftPanel, 'push');
            app.AddFolderButton.ButtonPushedFcn = createCallbackFcn(app, @AddFolderButtonPushed, true);
            app.AddFolderButton.Text = 'Add Folder';
            app.AnalyzeSelectedFilesButton = uibutton(app.LeftPanel, 'push');
            app.AnalyzeSelectedFilesButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeSelectedFilesButtonPushed, true);
            app.AnalyzeSelectedFilesButton.Text = 'Analyze Selected Files';
            app.ColorchannelEditFieldLabel = uilabel(app.LeftPanel);
            app.ColorchannelEditFieldLabel.Text = 'Color channel';
            app.ColorchannelEditField = uieditfield(app.LeftPanel, 'text');
            app.ColorchannelEditField.Editable = 'off';
            app.NumberoffilesEditFieldLabel = uilabel(app.LeftPanel);
            app.NumberoffilesEditFieldLabel.Text = 'Number of files';
            app.NumberoffilesEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NumberoffilesEditField.Limits = [0 Inf];
            app.NumberoffilesEditField.Editable = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.IndividualimageanalysisTab = uitab(app.TabGroup);
            app.IndividualimageanalysisTab.Title = 'Individual image analysis';
            app.UITable = uitable(app.IndividualimageanalysisTab);
            app.UITable.ColumnName = {'#'; 'File Name'; 'Mean Intensity'; 'Percentile Intensity'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true false true true];
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UIAxes_histSelect = uiaxes(app.IndividualimageanalysisTab);
            title(app.UIAxes_histSelect, 'Selected Image Histogram')
            zlabel(app.UIAxes_histSelect, 'Z')
            app.UIAxes_imSelect = uiaxes(app.IndividualimageanalysisTab);
            title(app.UIAxes_imSelect, 'Selected Image')
            app.UIAxes_imSelect.XTick = [];
            app.UIAxes_imSelect.YTick = [];
            app.FiguresbasedonallimagesTab = uitab(app.TabGroup);
            app.FiguresbasedonallimagesTab.Title = 'Figures based on all images';
            app.UIAxes_histTotal = uiaxes(app.FiguresbasedonallimagesTab);
            title(app.UIAxes_histTotal, 'Total Histogram')
            zlabel(app.UIAxes_histTotal, 'Z')
            app.UIAxes_cdfTotal = uiaxes(app.FiguresbasedonallimagesTab);
            title(app.UIAxes_cdfTotal, 'Total Cumulative Density Function')
            zlabel(app.UIAxes_cdfTotal, 'Z')
            app.UIAxes_endpoints = uiaxes(app.FiguresbasedonallimagesTab);
            title(app.UIAxes_endpoints, 'Scatterplot of Background Endpoints')
            ylabel(app.UIAxes_endpoints, 'Intensity')
            app.StatsbasedonallimagesPanel = uipanel(app.RightPanel);
            app.StatsbasedonallimagesPanel.Title = 'Stats based on all images';
            app.PercentileSpinnerLabel = uilabel(app.StatsbasedonallimagesPanel);
            app.PercentileSpinnerLabel.Text = 'Percentile';
            app.PercentileSpinner = uispinner(app.StatsbasedonallimagesPanel);
            app.PercentileSpinner.Step = 0.5;
            app.PercentileSpinner.Limits = [0 100];
            app.PercentileSpinner.ValueChangedFcn = createCallbackFcn(app, @PercentileSpinnerValueChanged, true);
            app.PercentileSpinner.Value = 99.5;
            app.PercentileintensityEditFieldLabel = uilabel(app.StatsbasedonallimagesPanel);
            app.PercentileintensityEditFieldLabel.Text = 'Percentile intensity';
            app.PercentileintensityEditField = uieditfield(app.StatsbasedonallimagesPanel, 'numeric');
            app.PercentileintensityEditField.ValueChangedFcn = createCallbackFcn(app, @PercentileintensityEditFieldValueChanged, true);
            app.TotalMaximumEditFieldLabel = uilabel(app.StatsbasedonallimagesPanel);
            app.TotalMaximumEditFieldLabel.Text = 'Total Maximum';
            app.TotalMaximumEditField = uieditfield(app.StatsbasedonallimagesPanel, 'numeric');
            app.TotalMaximumEditField.Editable = 'off';
            app.TotalMinimumEditFieldLabel = uilabel(app.StatsbasedonallimagesPanel);
            app.TotalMinimumEditFieldLabel.Text = 'Total Minimum';
            app.TotalMinimumEditField = uieditfield(app.StatsbasedonallimagesPanel, 'numeric');
            app.TotalMinimumEditField.Editable = 'off';
            app.TotalMeanEditFieldLabel = uilabel(app.StatsbasedonallimagesPanel);
            app.TotalMeanEditFieldLabel.Text = 'Total Mean';
            app.TotalMeanEditField = uieditfield(app.StatsbasedonallimagesPanel, 'numeric');
            app.TotalMeanEditField.Editable = 'off';
            app.UIAxes_imAVG = uiaxes(app.StatsbasedonallimagesPanel);
            title(app.UIAxes_imAVG, 'Average Image')
            app.UIAxes_imAVG.XTick = [];
            app.UIAxes_imAVG.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AverageHistogramAnalysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end